classdef proyeccion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        CentroyEditField           matlab.ui.control.NumericEditField
        CentroyEditFieldLabel      matlab.ui.control.Label
        CentroxEditField           matlab.ui.control.NumericEditField
        CentroxEditFieldLabel      matlab.ui.control.Label
        EnviarButton               matlab.ui.control.Button
        rEditField                 matlab.ui.control.NumericEditField
        rEditFieldLabel            matlab.ui.control.Label
        REditField                 matlab.ui.control.NumericEditField
        REditFieldLabel            matlab.ui.control.Label
        CrculoButton               matlab.ui.control.Button
        EpicicloideButton          matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        DesconectarSegmentoButton  matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        coord % Vector de las coordenadas clickeadas
        circle = false;
        epic = false;
        Rdec = 27;
        Gdec = 27;
        Bdec = 27;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cla (app.UIAxes2,'reset')
            hold (app.UIAxes2,'on')
            hold (app.UIAxes,'on')
            [X Y Z] = sphere;
            surf(app.UIAxes2,X,Y,Z, 'FaceAlpha',0.01, "LineStyle","-", 'EdgeAlpha',0.5);
            xlabel(app.UIAxes2,'x');
            ylabel(app.UIAxes2,'y');
            zlabel(app.UIAxes2,'z');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {487, 487};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {433, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.UIAxes.XLimMode = "manual";
            app.UIAxes.YLimMode = "manual";
            app.UIAxes.XLim = [-5, 5];
            app.UIAxes.YLim = [-5, 5];
            temp = app.UIAxes.CurrentPoint % Returns 2x3 array of points
            if temp(1,1) <= 5 && temp(1,1) >= -5
                if temp(1,2) <= 5 && temp(1,2) >= -5
                    loc = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates
                    app.coord = [app.coord loc];
                    disp(app.coord);
                    if app.Rdec < 205
                        if app.Gdec < 205
                            if app.Bdec < 205
                                app.Bdec = app.Bdec + 40
                            else
                                app.Bdec = 27;
                                app.Gdec = app.Gdec + 40;
                            end
                        else
                            app.Gdec = 27;
                            app.Rdec = app.Rdec + 40;
                        end
                    else
                        app.Rdec = 27;
                        app.Gdec = 27;
                        app.Bdec = 27;
                    end
                    Rhex = dec2hex(app.Rdec);
                    Ghex = dec2hex(app.Gdec);
                    Bhex = dec2hex(app.Bdec);
                    color = "#" + Rhex + Ghex + Bhex;
                    n = length(app.coord);
                    if n >= 4
                        x2 = app.coord(n-1);
                        y2 = app.coord(n);
                        x1 = app.coord(n-3);
                        y1 = app.coord(n-2);
                        m = (y2-y1)/(x2-x1);
                        b = y1-m*x1;
                        X = linspace(x1, x2);
                        Y = m*X + b;
                        plot(app.UIAxes, X, Y, 'r', "Color", color);
                        [x, y, z] = arrayfun(@f_proyeccion_comp, X, Y);
                        plot3(app.UIAxes2,x, y, z, 'r', 'LineWidth',1, "Color", color);
                    end
				end
			end
        end

        % Button pushed function: EpicicloideButton
        function EpicicloideButtonPushed(app, event)
         
            if app.rEditField.Value * app.REditField.Value ~= 0
                app.EnviarButton.Enable = true;
                app.EnviarButton.Visible = true;
            else
                app.EnviarButton.Enable = false;
                app.EnviarButton.Visible = false;
            end
            app.epic = true;
            app.circle = false;
            app.REditFieldLabel.Visible = true;
            app.REditFieldLabel.Enable = true;
            app.REditField.Enable = true;
            app.REditField.Visible = true;
            app.REditField.Editable = true;
            app.rEditFieldLabel.Visible = true;
            app.rEditFieldLabel.Enable = true;
            app.rEditField.Enable = true;
            app.rEditField.Visible = true;
            app.rEditField.Editable = true;
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            value = app.REditField.Value;
            if app.rEditField.Enable
                if value * app.rEditField.Value ~= 0
                    app.EnviarButton.Enable = true;
                    app.EnviarButton.Visible = true;
                else
                    app.EnviarButton.Enable = false;
                    app.EnviarButton.Visible = false;
                end
            else
                if value ~= 0
                    app.EnviarButton.Enable = true;
                    app.EnviarButton.Visible = true;
                else
                    app.EnviarButton.Enable = false;
                    app.EnviarButton.Visible = false;
                end
            end
        end

        % Value changed function: rEditField
        function rEditFieldValueChanged(app, event)
            value = app.rEditField.Value;
            if value * app.REditField.Value ~= 0
                app.EnviarButton.Enable = true;
                app.EnviarButton.Visible = true;
            else
                app.EnviarButton.Enable = false;
                app.EnviarButton.Visible = false;
            end
        end

        % Button pushed function: CrculoButton
        function CrculoButtonPushed(app, event)
            if app.REditField.Value ~= 0
                app.EnviarButton.Enable = true;
                app.EnviarButton.Visible = true;
            else
                app.EnviarButton.Enable = false;
                app.EnviarButton.Visible = false;
            end
            app.circle = true;
            app.epic = false;
            app.REditFieldLabel.Visible = true;
            app.REditFieldLabel.Enable = true;
            app.REditField.Enable = true;
            app.REditField.Visible = true;
            app.REditField.Editable = true;
            app.rEditFieldLabel.Visible = false;
            app.rEditFieldLabel.Enable = false;
            app.rEditField.Enable = false;
            app.rEditField.Visible = false;
            app.rEditField.Editable = false;
        end

        % Button pushed function: DesconectarSegmentoButton
        function DesconectarSegmentoButtonPushed(app, event)
            app.coord = [];
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            if app.circle
                R = app.REditField.Value;
                theta = linspace(0,2*pi);
                x = 0 + R * cos(theta) + app.CentroxEditField.Value;
                y = 0 + R * sin(theta) + app.CentroyEditField.Value;
                plot(app.UIAxes,x,y);
                [x1, x2, x3] = arrayfun(@f_proyeccion_comp, x, y);
                plot3(app.UIAxes2, x1, x2, x3, "LineWidth", 1)
            elseif app.epic
                R = app.REditField.Value;
                r = app.rEditField.Value;
                theta = linspace(0, 8*pi, 400);
                x = (R + r) * cos(theta) - r * cos((theta * R/r) + theta) + app.CentroxEditField.Value;
                y = (R + r) * sin(theta) - r * sin((theta * R/r) + theta) + app.CentroyEditField.Value;
                plot(app.UIAxes, x, y)
                [x1, x2, x3] = arrayfun(@f_proyeccion_comp, x, y);
                plot3(app.UIAxes2, x1, x2, x3, "LineWidth", 1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, {'Plano complejo'; ''})
            xlabel(app.UIAxes, 'Re(z)')
            ylabel(app.UIAxes, 'Im(z)')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-5 5];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-4 -3 -2 -1 0 1 2 3 4];
            app.UIAxes.XTickLabel = {'-4'; '-3'; '-2'; '-1'; '1'; '2'; '3'; '4'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-4 -3 -2 -1 0 1 2 3 4];
            app.UIAxes.YTickLabel = {'-4'; '-3'; '-2'; '-1'; '1'; '2'; '3'; '4'};
            app.EpicicloideButton = uibutton(app.LeftPanel, 'push');
            app.EpicicloideButton.ButtonPushedFcn = createCallbackFcn(app, @EpicicloideButtonPushed, true);
            app.EpicicloideButton.Text = 'Epicicloide';
            app.CrculoButton = uibutton(app.LeftPanel, 'push');
            app.CrculoButton.ButtonPushedFcn = createCallbackFcn(app, @CrculoButtonPushed, true);
            app.CrculoButton.Text = 'Círculo';
            app.REditFieldLabel = uilabel(app.LeftPanel);
            app.REditFieldLabel.Enable = 'off';
            app.REditFieldLabel.Visible = 'off';
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.LeftPanel, 'numeric');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.REditField.Editable = 'off';
            app.REditField.Enable = 'off';
            app.REditField.Visible = 'off';
            app.rEditFieldLabel = uilabel(app.LeftPanel);
            app.rEditFieldLabel.Enable = 'off';
            app.rEditFieldLabel.Visible = 'off';
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.LeftPanel, 'numeric');
            app.rEditField.ValueChangedFcn = createCallbackFcn(app, @rEditFieldValueChanged, true);
            app.rEditField.Editable = 'off';
            app.rEditField.Enable = 'off';
            app.rEditField.Visible = 'off';
            app.EnviarButton = uibutton(app.LeftPanel, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Enable = 'off';
            app.EnviarButton.Visible = 'off';
            app.EnviarButton.Text = 'Enviar';
            app.CentroxEditFieldLabel = uilabel(app.LeftPanel);
            app.CentroxEditFieldLabel.Text = 'Centro : x';
            app.CentroxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CentroyEditFieldLabel = uilabel(app.LeftPanel);
            app.CentroyEditFieldLabel.Text = 'Centro : y';
            app.CentroyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.DesconectarSegmentoButton = uibutton(app.RightPanel, 'push');
            app.DesconectarSegmentoButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarSegmentoButtonPushed, true);
            app.DesconectarSegmentoButton.Text = 'Desconectar Segmento';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyeccion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end